classdef Keithley2450_DoubleGateSweep < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        BaselineVEditField            matlab.ui.control.NumericEditField
        BaselineVEditField_2Label     matlab.ui.control.Label
        CompliancemAEditField_2       matlab.ui.control.NumericEditField
        CompliancemAEditField_2Label  matlab.ui.control.Label
        InstrDropDown                 matlab.ui.control.DropDown
        InstrDropDownLabel            matlab.ui.control.Label
        Gate2450SettingsLabel         matlab.ui.control.Label
        MaxVEditField                 matlab.ui.control.NumericEditField
        MaxVEditFieldLabel            matlab.ui.control.Label
        MinVEditField                 matlab.ui.control.NumericEditField
        MinVEditFieldLabel            matlab.ui.control.Label
        OffTimesEditField             matlab.ui.control.NumericEditField
        OffTimesEditFieldLabel        matlab.ui.control.Label
        StepSizeVEditField            matlab.ui.control.NumericEditField
        StepSizeVEditFieldLabel       matlab.ui.control.Label
        NoofStepsEditField            matlab.ui.control.NumericEditField
        NoofStepsEditFieldLabel       matlab.ui.control.Label
        PulseWidthsEditField          matlab.ui.control.NumericEditField
        Ratemin1Label                 matlab.ui.control.Label
        GridLayout_RunButtonCentring  matlab.ui.container.GridLayout
        ScanFileSetupControlPanel     Palladium.Instruments.Controls.ScanFileSetupControlPanel
        TitleLabel                    matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        Measurement2450SettingsLabel  matlab.ui.control.Label
        CompliancemAEditField         matlab.ui.control.NumericEditField
        CompliancemAEditFieldLabel    matlab.ui.control.Label
        BiasVEditField                matlab.ui.control.NumericEditField
        BiasVEditFieldLabel           matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        Abort
        InsertSmartTag
        Run
    end


    methods (Access = public)

        %% EnableRunButton
        function EnableRunButton(comp)
            comp.ScanFileSetupControlPanel.EnableRunButton();
        end

        %% InsertAutonamingtagButtonPushed
        function InsertAutonamingtagButtonPushed(comp, value)
            eventArgs = Palladium.Events.ValueChangedEventData(value);
            notify(comp, "InsertSmartTag", eventArgs); 
        end

        %% IsContinuousSelected
        function tf = IsContinuousSelected(comp)
            tf = comp.ScanFileSetupControlPanel.IsContinuousSelected();
        end

        %% SetInstrumentList
        function SetInstrumentList(comp, listOfInstrs)
            
            if isempty(listOfInstrs)
                comp.InstrDropDown.Items = [];
                return;
            end
            
            for i = 1 : length(listOfInstrs)
                names(i) = listOfInstrs(i).Name;
                dat(i) = listOfInstrs(i);
            end

            comp.InstrDropDown.Items = names;
            comp.InstrDropDown.ItemsData = dat;
        end

        %% SetLimits
        function SetLimits(comp, min, max)
            comp.CompliancemAEditField.Limits = [min max];
            comp.BaselineVEditField.Limits = [min max];
            comp.BiasVEditField.Limits = [min max];
        end

        %% SetTitle
        function SetTitle(comp, title)
            comp.TitleLabel.Text = title;
        end

        %% SweepComplete
        function SweepComplete(comp)
            %Call this from external code once the sweep is complete and we are ready to run
            %once again
            comp.SetReady();
            drawnow();
        end

        %% OnAbortButtonPushed
        function OnAbortButtonPushed(comp)

            %Return to state ready to Run again
            comp.SetReady();
            
            %Trigger event
            eventData = Palladium.Instruments.Events.SweepSetupEventData([]);
            notify(comp, 'Abort', eventData);
        end

        %% OnRunButtonPushed
        function OnRunButtonPushed(comp)
            %Make sure we have all the right up-to-date numbers in the
            %SweepData struct
            sweepData = comp.GatherSettings();
            
            %Turn Run button into Abort button
            comp.SetRunning();
            
            %Trigger event
            eventData = Palladium.Instruments.Events.SweepSetupEventData(sweepData);
            notify(comp, 'Run', eventData);

        end

        %% RunSweep
        function RunSweep(comp)
            %Entry point for external code instead of button presses
            comp.OnRunButtonPushed();            
        end
    end

    methods (Access = private)

        %% GatherSettings
        function sweepData = GatherSettings(comp)            
            %Assign sweep information into the SweepData struct that
            %encodes all an instrument will need to know about this sweep

            %Measurement 2450 Settings
            sweepData.BiasV = comp.BiasVEditField.Value;
            sweepData.Meas_Compliance_mA = comp.CompliancemAEditField.Value;

            %Gate 2450 Settings
            sweepData.GateInstr = comp.InstrDropDown.ItemsData(comp.InstrDropDown.ValueIndex);
            sweepData.Gate_Compliance_mA = comp.CompliancemAEditField_2.Value;
            sweepData.Baseline_V = comp.BaselineVEditField.Value;
            sweepData.StepSize_V = comp.StepSizeVEditField.Value;
            sweepData.PulseWidth_s = comp.PulseWidthsEditField.Value;
            sweepData.OffTime_s = comp.OffTimesEditField.Value;

            %Save file Settings
            scanDetails = comp.ScanFileSetupControlPanel.CollectScanDetails();
            sweepData.SaveSweepFile = scanDetails.SaveSweepFile;
            sweepData.FileName = scanDetails.FileName;
        end

        %% SetReady
        function SetReady(comp)         
            comp.BiasVEditField.Enable = "on";
            comp.CompliancemAEditField.Enable = "on";

            comp.InstrDropDown.Enable = "on";
            comp.CompliancemAEditField_2.Enable = "on";
            comp.BaselineVEditField.Enable = "on";
            comp.StepSizeVEditField.Enable = "on";
            comp.NoofStepsEditField.Enable = "on";
            comp.PulseWidthsEditField.Enable = "on";
            comp.OffTimesEditField.Enable = "on";

            comp.ScanFileSetupControlPanel.SetReady();
        end

        %% SetRunning
        function SetRunning(comp)
            comp.ScanFileSetupControlPanel.SetRunning();

            comp.BiasVEditField.Enable = "off";
            comp.CompliancemAEditField.Enable = "off";

            comp.InstrDropDown.Enable = "off";
            comp.CompliancemAEditField_2.Enable = "off";
            comp.BaselineVEditField.Enable = "off";
            comp.StepSizeVEditField.Enable = "off";
            comp.NoofStepsEditField.Enable = "off";
            comp.PulseWidthsEditField.Enable = "off";
            comp.OffTimesEditField.Enable = "off";
        end

        %% UpdateVoltageSpan
        function UpdateVoltageSpan(comp)
            comp.MinVEditField.Value = comp.BaselineVEditField.Value - comp.NoofStepsEditField.Value * comp.StepSizeVEditField.Value;
            comp.MaxVEditField.Value = comp.BaselineVEditField.Value + comp.NoofStepsEditField.Value * comp.StepSizeVEditField.Value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.update();

            comp.ScanFileSetupControlPanel.HideDataColumnAddPanel();
            comp.UpdateVoltageSpan();    
            
            addlistener(comp.ScanFileSetupControlPanel, 'InsertSmartTag', @(src,evnt)comp.InsertAutonamingtagButtonPushed(evnt.Value));
        end

        % Callback function: ScanFileSetupControlPanel
        function RunButtonPushed(comp, event)
             comp.OnRunButtonPushed();
        end

        % Callback function: not associated with a component
        function BaselineVEditFieldValueChanged(comp, event)
            %Make sure values are small -> big, in the right order
            if(comp.BaselineVEditField.Value > comp.BiasVEditField.Value)
                comp.BaselineVEditField.Value = comp.BiasVEditField.Value;
            end
            if(comp.BaselineVEditField.Value < comp.CompliancemAEditField.Value)
                comp.BaselineVEditField.Value = comp.CompliancemAEditField.Value;
            end
            
            value = comp.BaselineVEditField.Value;
            comp.MidVal = value;            
        end

        % Value changed function: StepSizeVEditField
        function StepSizeVEditFieldValueChanged(comp, event)
            comp.UpdateVoltageSpan();        
        end

        % Value changed function: NoofStepsEditField
        function NoofStepsEditFieldValueChanged(comp, event)
             comp.UpdateVoltageSpan();
        end

        % Callback function: ScanFileSetupControlPanel
        function AbortButtonPushed(comp, event)
            comp.OnAbortButtonPushed();
        end

        % Value changed function: BaselineVEditField
        function BaselineVEditFieldValueChanged2(comp, event)
            comp.UpdateVoltageSpan();
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 520 755];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.BiasVEditFieldLabel = uilabel(comp.GridLayout2);
            comp.BiasVEditFieldLabel.Text = 'Bias (V)';
            comp.BiasVEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.BiasVEditField.Value = 1;
            comp.CompliancemAEditFieldLabel = uilabel(comp.GridLayout2);
            comp.CompliancemAEditFieldLabel.Text = 'Compliance (mA)';
            comp.CompliancemAEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.CompliancemAEditField.Limits = [0 Inf];
            comp.CompliancemAEditField.Value = 0.1;
            comp.Measurement2450SettingsLabel = uilabel(comp.GridLayout2);
            comp.Measurement2450SettingsLabel.Text = 'Measurement 2450 Settings';
            comp.TitleLabel = uilabel(comp.GridLayout);
            comp.TitleLabel.Text = 'Keithley 2450 Double Gate Sweep';
            comp.GridLayout_RunButtonCentring = uigridlayout(comp.GridLayout);
            comp.ScanFileSetupControlPanel = Palladium.Instruments.Controls.ScanFileSetupControlPanel(comp.GridLayout_RunButtonCentring);
            comp.ScanFileSetupControlPanel.AbortPushedFcn = matlab.apps.createCallbackFcn(comp, @AbortButtonPushed, true);
            comp.ScanFileSetupControlPanel.RunPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.GridLayout10 = uigridlayout(comp.GridLayout);
            comp.Ratemin1Label = uilabel(comp.GridLayout10);
            comp.Ratemin1Label.Text = 'Pulse Width (s)';
            comp.PulseWidthsEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.PulseWidthsEditField.Limits = [0 Inf];
            comp.PulseWidthsEditField.Value = 0.1;
            comp.NoofStepsEditFieldLabel = uilabel(comp.GridLayout10);
            comp.NoofStepsEditFieldLabel.Text = 'No. of Steps';
            comp.NoofStepsEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.NoofStepsEditField.Limits = [1 Inf];
            comp.NoofStepsEditField.RoundFractionalValues = 'on';
            comp.NoofStepsEditField.ValueDisplayFormat = '%d';
            comp.NoofStepsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NoofStepsEditFieldValueChanged, true);
            comp.NoofStepsEditField.Value = 1;
            comp.StepSizeVEditFieldLabel = uilabel(comp.GridLayout10);
            comp.StepSizeVEditFieldLabel.Text = 'Step Size (V)';
            comp.StepSizeVEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.StepSizeVEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @StepSizeVEditFieldValueChanged, true);
            comp.StepSizeVEditField.Value = 0.1;
            comp.OffTimesEditFieldLabel = uilabel(comp.GridLayout10);
            comp.OffTimesEditFieldLabel.Text = 'Off Time (s)';
            comp.OffTimesEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.OffTimesEditField.Limits = [0 Inf];
            comp.MinVEditFieldLabel = uilabel(comp.GridLayout10);
            comp.MinVEditFieldLabel.Text = 'Min V';
            comp.MinVEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.MinVEditField.AllowEmpty = 'on';
            comp.MinVEditField.Editable = 'off';
            comp.MinVEditField.Value = [];
            comp.MaxVEditFieldLabel = uilabel(comp.GridLayout10);
            comp.MaxVEditFieldLabel.Text = 'Max V';
            comp.MaxVEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.MaxVEditField.AllowEmpty = 'on';
            comp.MaxVEditField.Editable = 'off';
            comp.MaxVEditField.Value = [];
            comp.Gate2450SettingsLabel = uilabel(comp.GridLayout10);
            comp.Gate2450SettingsLabel.Text = 'Gate 2450 Settings';
            comp.InstrDropDownLabel = uilabel(comp.GridLayout10);
            comp.InstrDropDownLabel.Text = 'Instr';
            comp.InstrDropDown = uidropdown(comp.GridLayout10);
            comp.InstrDropDown.Items = {};
            comp.InstrDropDown.Value = {};
            comp.CompliancemAEditField_2Label = uilabel(comp.GridLayout10);
            comp.CompliancemAEditField_2Label.Text = 'Compliance (mA)';
            comp.CompliancemAEditField_2 = uieditfield(comp.GridLayout10, 'numeric');
            comp.CompliancemAEditField_2.Limits = [0 Inf];
            comp.CompliancemAEditField_2.Value = 0.1;
            comp.BaselineVEditField_2Label = uilabel(comp.GridLayout10);
            comp.BaselineVEditField_2Label.Text = 'Baseline (V)';
            comp.BaselineVEditField = uieditfield(comp.GridLayout10, 'numeric');
            comp.BaselineVEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @BaselineVEditFieldValueChanged2, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end