classdef MFLISweepControlPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout_FileWriteOptions    matlab.ui.container.GridLayout
        FileNameEditField              matlab.ui.control.EditField
        SavetonewfileCheckBox          matlab.ui.control.CheckBox
        GridLayout_CommandButtons      matlab.ui.container.GridLayout
        ContinuousCheckBox             matlab.ui.control.CheckBox
        StopButton                     matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        GridLayout_ParameterValues     matlab.ui.container.GridLayout
        TimeConstantEditField          matlab.ui.control.NumericEditField
        TimeConstantEditFieldLabel     matlab.ui.control.Label
        UnitsLabel_5                   matlab.ui.control.Label
        UnitsLabel_4                   matlab.ui.control.Label
        UnitsLabel_3                   matlab.ui.control.Label
        AveTcEditField                 matlab.ui.control.NumericEditField
        AveTcEditFieldLabel            matlab.ui.control.Label
        AveSampleEditField             matlab.ui.control.NumericEditField
        AveSampleEditFieldLabel        matlab.ui.control.Label
        SweepInaccuracyEditField       matlab.ui.control.NumericEditField
        SweepInaccuracyEditFieldLabel  matlab.ui.control.Label
        BandwidthEditField             matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel        matlab.ui.control.Label
        MeasurementSettingsLabel       matlab.ui.control.Label
        LogScaleLabel                  matlab.ui.control.Label
        LogScaleCheckBox               matlab.ui.control.CheckBox
        UnitsLabel_2                   matlab.ui.control.Label
        UnitsLabel_1                   matlab.ui.control.Label
        NumStepsEditField              matlab.ui.control.NumericEditField
        NumStepsEditFieldLabel         matlab.ui.control.Label
        EndEditField                   matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        StartEditField                 matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        SweepParameterDropDown         matlab.ui.control.DropDown
        SweepParameterDropDownLabel    matlab.ui.control.Label
        SweepParametersLabel           matlab.ui.control.Label
        SettleTimeEditField            matlab.ui.control.NumericEditField
        SettleTimeEditFieldLabel       matlab.ui.control.Label
        FilterOrderEditField           matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        MFLISweepControlLabel          matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        RunSingleSweep
        StopSweep
    end


    methods (Access = private)

        %% CollectSettings
        function [SweepName, SweepParams, FileParams] = CollectSettings(comp)

            SweepName = string(comp.SweepParameterDropDown.Value);

            SweepParams.Start = comp.StartEditField.Value;
            SweepParams.Stop = comp.EndEditField.Value;
            SweepParams.NumberOfSteps = comp.NumStepsEditField.Value;
            SweepParams.LogScale = comp.LogScaleCheckBox.Value;

            SweepParams.Bandwidth = comp.BandwidthEditField.Value;
            SweepParams.FilterOrder = comp.FilterOrderEditField.Value;

            SweepParams.SettleTime  = comp.SettleTimeEditField.Value;
            SweepParams.SweepInaccuracy = comp.SweepInaccuracyEditField.Value;

            SweepParams.AveSample = comp.AveSampleEditField.Value;
            SweepParams.AveTC = comp.AveTcEditField.Value;

            %File saving
            FileParams.SaveSweepFile = comp.SavetonewfileCheckBox.Value;
            FileParams.FileName = comp.FileNameEditField.Value;
        end

        %% CanStart
        function canStart = CanStart(comp, SweepParams)
            canStart = true;

            if(SweepParams.NumberOfSteps < 1)
                canStart = false;
                warndlg("Invalid sweep parameters, cannot start");
            end
        end

        %% OnRunSweepButtonPushed
        function OnRunSweepButtonPushed(comp)
            [SweepName, SweepParams, FileParams] = comp.CollectSettings();

            if ~comp.CanStart(SweepParams)
                return;
            end

            EventData.SweepName = SweepName;
            EventData.SweepParams = SweepParams;
            EventData.FileParams = FileParams;

            ed = Palladium.Events.ValueChangedEventData(EventData);
            notify(comp, "RunSingleSweep", ed);

            comp.SetRunning();
            drawnow();
        end

        %% OnStopButtonPushed
        function OnStopButtonPushed(comp)
            comp.AbortSweep();
        end

        %% OnSweepXAxisParameterSelectionChanged
        function OnSweepXAxisParameterSelectionChanged(comp, value)

            switch(value)
                case("Frequency")
                    units = "Hz";
                case("AuxOutput1")
                    units = "V";
                case("OutputOffset")
                    units = "V";
                otherwise
                    error(['Invalid Sweep Parameter for function. ' ...
                        'SweptParameter: Frequency, AuxOutput1, OutputOffset'])
            end

            comp.SetXUnits(units);
        end

        %% SetXUnits
        function SetXUnits(comp, units)
            comp.UnitsLabel_1.Text = units;
            comp.UnitsLabel_2.Text = units;
        end

        %% SetReady
        function SetReady(comp)
            comp.StopButton.Enable = "off";
            comp.RunButton.Enable = "on";
        end

        %% SetRunning
        function SetRunning(comp)
            comp.StopButton.Enable = "on";
            comp.RunButton.Enable = "off";
        end

    end

    methods (Access = public)

        %% AbortSweep
        function AbortSweep(comp)
            notify(comp, "StopSweep");
            comp.SetReady();
            drawnow();
        end

        %% IsContinuousSelected
        function cont = IsContinuousSelected(comp)
            cont = comp.ContinuousCheckBox.Value;
        end

        %% LockRunButton
        function LockRunButton(comp)
            comp.RunButton.Enable = "off";
        end

        %% RunSweep
        function RunSweep(comp)
            comp.OnRunSweepButtonPushed();
        end

        %% SetName
        function SetName(comp, nameString)
            comp.MFLISweepControlLabel.Text = nameString;
        end

        %% SweepComplete
        function SweepComplete(comp)
            comp.SetReady();
        end
        
        %% UnlockRunButton
        function UnlockRunButton(comp)
            comp.RunButton.Enable = "on";
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)
            comp.RunSweep();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(comp, event)
            comp.OnStopButtonPushed();
        end

        % Value changed function: SweepParameterDropDown
        function SweepParameterDropDownValueChanged(comp, event)
            value = comp.SweepParameterDropDown.Value;
            comp.OnSweepXAxisParameterSelectionChanged(string(value));
        end

        % Value changed function: TimeConstantEditField
        function TimeConstantEditFieldValueChanged(comp, event)
            value = comp.TimeConstantEditField.Value;

            %Time constant and bandwidth are the same parameter, presented in 2 ways
            %for convenience. Update one when the other changes
            comp.BandwidthEditField.Value = 1 / value;
        end

        % Value changed function: BandwidthEditField
        function BandwidthEditFieldValueChanged(comp, event)
            value = comp.BandwidthEditField.Value;

            %Time constant and bandwidth are the same parameter, presented in 2 ways
            %for convenience. Update one when the other changes
            comp.TimeConstantEditField.Value = 1 / value;
        end

        % Value changed function: SavetonewfileCheckBox
        function SavetonewfileCheckBoxValueChanged(comp, event)
            value = comp.SavetonewfileCheckBox.Value;
            %Toggle the editing of the file name edit field as a visual
            %clue
            if value
                comp.FileNameEditField.Enable = "on";
            else
                comp.FileNameEditField.Enable = "off";
            end

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 696 643];
            comp.GridLayout = uigridlayout(comp);
            comp.MFLISweepControlLabel = uilabel(comp.GridLayout);
            comp.MFLISweepControlLabel.Text = 'MFLI Sweep Control';
            comp.GridLayout_ParameterValues = uigridlayout(comp.GridLayout);
            comp.FilterOrderEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.FilterOrderEditFieldLabel.Text = 'FilterOrder';
            comp.FilterOrderEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.FilterOrderEditField.Limits = [0 8];
            comp.FilterOrderEditField.RoundFractionalValues = 'on';
            comp.FilterOrderEditField.Tooltip = {'As in the LabOne GUI, filterorder is the order of the bandpass filters doing demodulation. 3-4 is normal, higher is better but slower.'};
            comp.FilterOrderEditField.Value = 4;
            comp.SettleTimeEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.SettleTimeEditFieldLabel.Text = 'Settle Time';
            comp.SettleTimeEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.SettleTimeEditField.Limits = [0 Inf];
            comp.SettleTimeEditField.Tooltip = {'Minimum wait time in seconds between a sweep parameter change and the recording of the next sweep point'};
            comp.SettleTimeEditField.Value = 0.3;
            comp.SweepParametersLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.SweepParametersLabel.Text = 'Sweep Parameters';
            comp.SweepParameterDropDownLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.SweepParameterDropDownLabel.Text = 'Sweep Parameter';
            comp.SweepParameterDropDown = uidropdown(comp.GridLayout_ParameterValues);
            comp.SweepParameterDropDown.Items = {'Frequency', 'AuxOutput1', 'OutputOffset'};
            comp.SweepParameterDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SweepParameterDropDownValueChanged, true);
            comp.SweepParameterDropDown.Tooltip = {'Select the parameter to sweep over:'; ''; '- Frequency - simple frequency sweep'; ''; '- Aux Output 1 - dI/dV sweep. Make sure to loop Aux Out 1 into Aux In 1 and enable Add in the Signal Out. This then adds the Aux Out as a dc offset to the AC Signal Out (but lets you keep a small precise range on the AC modulation).'; ''; '- OutputOffset - As above but simpler and without that gain in Signal Output range. Just adds an offset directly to SO1, but within its voltage range.'};
            comp.SweepParameterDropDown.Value = 'Frequency';
            comp.StartEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.StartEditFieldLabel.Text = 'Start';
            comp.StartEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.StartEditField.Value = 100;
            comp.EndEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.EndEditFieldLabel.Text = 'End';
            comp.EndEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.EndEditField.Value = 10000;
            comp.NumStepsEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.NumStepsEditFieldLabel.Text = 'Num Steps';
            comp.NumStepsEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.NumStepsEditField.Limits = [1 Inf];
            comp.NumStepsEditField.RoundFractionalValues = 'on';
            comp.NumStepsEditField.Value = 30;
            comp.UnitsLabel_1 = uilabel(comp.GridLayout_ParameterValues);
            comp.UnitsLabel_1.Text = 'Hz';
            comp.UnitsLabel_2 = uilabel(comp.GridLayout_ParameterValues);
            comp.UnitsLabel_2.Text = 'Hz';
            comp.LogScaleCheckBox = uicheckbox(comp.GridLayout_ParameterValues);
            comp.LogScaleCheckBox.Text = '';
            comp.LogScaleLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.LogScaleLabel.Text = 'Log Scale';
            comp.MeasurementSettingsLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.MeasurementSettingsLabel.Text = 'Measurement Settings';
            comp.BandwidthEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.BandwidthEditFieldLabel.Text = 'Bandwidth';
            comp.BandwidthEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.BandwidthEditField.Limits = [0 Inf];
            comp.BandwidthEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @BandwidthEditFieldValueChanged, true);
            comp.BandwidthEditField.Tooltip = {'Time constant and Bandwidth are related - here just by inverse - and will update together. Real values involve the filter order and are more complex.'};
            comp.BandwidthEditField.Value = 10;
            comp.SweepInaccuracyEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.SweepInaccuracyEditFieldLabel.Text = 'Sweep Inaccuracy';
            comp.SweepInaccuracyEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.SweepInaccuracyEditField.Limits = [0 Inf];
            comp.SweepInaccuracyEditField.Tooltip = {'Fractional error to accept when waiting for demod to approach final stability while sleeping. 1e-5 works nicely without being too slow. Smaller values will be more accurate but slower.'};
            comp.SweepInaccuracyEditField.Value = 0.0001;
            comp.AveSampleEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.AveSampleEditFieldLabel.Text = 'Ave Sample';
            comp.AveSampleEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.AveSampleEditField.Limits = [0 Inf];
            comp.AveSampleEditField.RoundFractionalValues = 'on';
            comp.AveSampleEditField.Tooltip = {'Sets the effective number of samples (clock cycles) per sweeper parameter point that is considered in the measurement.'};
            comp.AveSampleEditField.Value = 100;
            comp.AveTcEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.AveTcEditFieldLabel.Text = 'Ave Tc';
            comp.AveTcEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.AveTcEditField.Limits = [0 Inf];
            comp.AveTcEditField.RoundFractionalValues = 'on';
            comp.AveTcEditField.Tooltip = {'Effective calculation time is the maximum between samples and number of time constants. Usually set the Sample Count.'};
            comp.AveTcEditField.Value = 1;
            comp.UnitsLabel_3 = uilabel(comp.GridLayout_ParameterValues);
            comp.UnitsLabel_3.Text = 's';
            comp.UnitsLabel_4 = uilabel(comp.GridLayout_ParameterValues);
            comp.UnitsLabel_4.Text = 'Hz';
            comp.UnitsLabel_5 = uilabel(comp.GridLayout_ParameterValues);
            comp.UnitsLabel_5.Text = 's';
            comp.TimeConstantEditFieldLabel = uilabel(comp.GridLayout_ParameterValues);
            comp.TimeConstantEditFieldLabel.Text = 'Time Constant';
            comp.TimeConstantEditField = uieditfield(comp.GridLayout_ParameterValues, 'numeric');
            comp.TimeConstantEditField.Limits = [0 Inf];
            comp.TimeConstantEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TimeConstantEditFieldValueChanged, true);
            comp.TimeConstantEditField.Tooltip = {'Time constant and Bandwidth are related - here just by inverse - and will update together. Real values involve the filter order and are more complex.'};
            comp.TimeConstantEditField.Value = 0.1;
            comp.GridLayout_CommandButtons = uigridlayout(comp.GridLayout);
            comp.RunButton = uibutton(comp.GridLayout_CommandButtons, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Enable = 'off';
            comp.RunButton.Text = 'Run';
            comp.StopButton = uibutton(comp.GridLayout_CommandButtons, 'push');
            comp.StopButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StopButtonPushed, true);
            comp.StopButton.Enable = 'off';
            comp.StopButton.Text = 'Stop';
            comp.ContinuousCheckBox = uicheckbox(comp.GridLayout_CommandButtons);
            comp.ContinuousCheckBox.Tooltip = {'Set to true to cause a new Sweep to immediately Run once the previous one finishes.'};
            comp.ContinuousCheckBox.Text = 'Continuous';
            comp.GridLayout_FileWriteOptions = uigridlayout(comp.GridLayout);
            comp.SavetonewfileCheckBox = uicheckbox(comp.GridLayout_FileWriteOptions);
            comp.SavetonewfileCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SavetonewfileCheckBoxValueChanged, true);
            comp.SavetonewfileCheckBox.Tooltip = {'Select this to cause a seperate named data file to be created for each time Run is hit, with the specified name'};
            comp.SavetonewfileCheckBox.Text = '  Save to new file';
            comp.SavetonewfileCheckBox.Value = true;
            comp.FileNameEditField = uieditfield(comp.GridLayout_FileWriteOptions, 'text');
            comp.FileNameEditField.Tooltip = {'Filename of the saved data will be the current overall file name, with this text appended. Enclose names of parameters in [brackets] to auto-populate names with data from the measurements, '; ''; 'eg. "Sweep at T = [Sample Temperature(K),%6.2f] K"'; ''; 'The second argument, and its comma delimiter, are optional, and tell the code how to format the numerical value as string. '; ''; 'Common options for the format string are:'; ''; '%.2f - round to 2 d.p.'; ''; '%.3G - round to 3s.f., no exponent notation'};
            comp.FileNameEditField.Value = ' - Sweep File';
        end
    end
end