classdef SequenceEditor_DefaultGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PalladiumSequenceEditorUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        QueueButton              matlab.ui.control.Button
        CommandEditFieldLabel    matlab.ui.control.Label
        CommandEditField         matlab.ui.control.EditField
        RunCommandsLabel         matlab.ui.control.Label
        InstrumentDropDown       matlab.ui.control.DropDown
        InstrumentDropDownLabel  matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        Tree                     matlab.ui.container.Tree
        GridLayout3              matlab.ui.container.GridLayout
        RefreshButton            matlab.ui.control.Button
        DirectoryLabel           matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        BrowseButton             matlab.ui.control.Button
    end


    properties (Access = private)
    end

    properties (Access = public)
        DefaultDir;
        FileExtension;
        SelectedDir;
        SelectedFileName;
    end

    events
        DirectorySelect;
        FileSelect;
        SingleCommandQueued;
    end


    methods (Access = public)

        %% DirectorySelected
        function DirectorySelected(app, dir)
            %Cache value
            eventArgs = Palladium.Events.ValueChangedEventData(dir);
            app.SelectedDir = dir;

            %Fire event
            notify(app, "DirectorySelect", eventArgs);
        end

        %% FileSelected
        function FileSelected(app, fileName)
            %Cache value
            eventArgs = Palladium.Events.ValueChangedEventData(fileName);
            app.SelectedFileName = fileName;

            %Fire event
            notify(app, "FileSelect", eventArgs);
        end

        %% OnDirectorySelected
        function OnDirectorySelected(app)
            app.TextArea.Value = app.SelectedDir;
            app.UpdateTree();
        end

        %% OnFileSelected
        function OnFileSelected(app) %#ok<MANU>

        end

        %% RefreshInstrumentNames
        function RefreshInstrumentNames(app, instrumentsList)
            names = strings(length(instrumentsList), 1);
            data = cell(length(instrumentsList), 1);

            for i = 1 : length(instrumentsList)
                names(i) = instrumentsList{i}.Name;
                data{i} = instrumentsList{i};
            end

            app.InstrumentDropDown.Items = names;
            app.InstrumentDropDown.ItemsData = data;
        end

        %% SeizeFocus
        function SeizeFocus(app)
            % Calling uigetfile or similar modal dialogs may send the app to the back.
            % Bring it back to the front, preserving maximised, minimised etc.
            windowState = app.PalladiumSequenceEditorUIFigure.WindowState;
            app.PalladiumSequenceEditorUIFigure.Visible = 'off';
            app.PalladiumSequenceEditorUIFigure.Visible = 'on';
            app.PalladiumSequenceEditorUIFigure.WindowState = windowState;
        end
        
        %% SetIcon
        function SetIcon(app, iconPath)
            app.PalladiumSequenceEditorUIFigure.Icon = iconPath;
        end

    end

    methods (Access = private)

        %% AddNodesForFolderContents
        function folderNodesToPopulate = AddNodesForFolderContents(app, rootNode, folderPath)
            folderNodesToPopulate = [];

            %Get contents of directory
            dirContents = dir(string(folderPath));

            %Add folders first
            for i = 1 : length(dirContents)
                item = dirContents(i);
                if item.isdir
                    if ~contains(item.name, '.')    %Exclude the '.' and '..' results one always gets
                        nodeStr = "📂 " + item.name;
                        foldrNode = uitreenode(rootNode, "Text", nodeStr, "UserData", item);
                        folderNodesToPopulate = [folderNodesToPopulate foldrNode]; %#ok<AGROW>
                    end
                end
            end

            %Then add files
            namesOfNodesToAdd = {};
            items = {};
            for i = 1 : length(dirContents)
                item = dirContents(i);
                if ~item.isdir
                    [~, ~, ext] = fileparts(item.name);
                    if ext == app.FileExtension
                        nodeStr = item.name;
                        items = [items, item]; %#ok<AGROW>
                        namesOfNodesToAdd = [namesOfNodesToAdd, nodeStr]; %#ok<AGROW>
                    end
                end
            end

            %Put this extra step in in case we want to sort or sift the
            %nodes before adding..
            for i = 1 : length(namesOfNodesToAdd)
                nodeStr = namesOfNodesToAdd{i};
                itemUserData = items{i};
                uitreenode(rootNode, "Text", nodeStr, "UserData", itemUserData);
            end
        end

        %% ClearTree
        function ClearTree(app)
            nodes = app.Tree.Children;
            nodes.delete;
        end

        %% GetCommandString
        function str = GetCommandString(app)
            str = app.CommandEditField.Value;
        end

        %% GetSelectedInstrument
        function instr = GetSelectedInstrument(app)
            idx = app.InstrumentDropDown.ValueIndex;
            itemData = app.InstrumentDropDown.ItemsData{idx};
            instr = itemData;
        end

        %% NodeSelectionChange
        function NodeSelectionChange(app, ~, event)
            % Function for the SelectionChangedFcn callback
            nodes = event.SelectedNodes;
            node = nodes(1);    %Multiselect is off so in fact only one node can ever be selected here
            app.OnSingleNodeSelected(node);
        end

        %% OnSingleNodeSelected
        function OnSingleNodeSelected(app, node) %#ok<INUSD>
            
        end

        %% UpdateTree
        function UpdateTree(app)
            app.ClearTree();

            [~, dirName, ~] = fileparts(app.SelectedDir);

            %Add single parent node showing the directory we are in
            nodeStr = "📂 " + dirName;
            rootNode = uitreenode(app.Tree, "Text", nodeStr, "UserData", []);

            %Add folders and files in the starting root directory
            folderNodesToPopulate = app.AddNodesForFolderContents(rootNode, app.SelectedDir);

            %If there were folders in there, look inside them, and keep
            %looking in subfolders until there are no more folders left in
            %the heirachy
            while ~isempty(folderNodesToPopulate)
                folderPathsToCheck = cell(length(folderNodesToPopulate), 1);
                folderNodes = cell(length(folderNodesToPopulate), 1);
                for i = 1 : length(folderNodesToPopulate)
                    path = fullfile(folderNodesToPopulate(i).UserData.folder, folderNodesToPopulate(i).UserData.name);
                    folderPathsToCheck{i} = path;
                    folderNodes{i} = folderNodesToPopulate(i);
                end

                folderNodesToPopulate = [];

                for i = 1 : length(folderNodes)
                    %Add folders and files in this directory being checked
                    folderNodesToPopulateInThisFolder = app.AddNodesForFolderContents(folderNodes{i}, folderPathsToCheck(i));
                    folderNodesToPopulate = [folderNodesToPopulate, folderNodesToPopulateInThisFolder]; %#ok<AGROW>
                end
            end

            % Expand the tree
            expand(app.Tree);

            %Set callback for selection change events
            app.Tree.SelectionChangedFcn = @app.NodeSelectionChange;

        end        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %Show dialogue to select new directory
            [dir] = uigetdir(app.DefaultDir, "📂 Select Directory");

            %Bring this GUI back to the front - Matlab has an annoying
            %habit of hiding it behind other windows when closing a modal
            %dialogue box
            app.SeizeFocus();

            %Handle case of cancelled dialogue box
            if dir == 0
                return;
            end

            %Set the selected directory to the chosen one
            app.SelectedDir = dir;

            %Trigger event to say this directory was selected - external
            %code will then update the GUI
            app.DirectorySelected(dir);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            %Trigger event to say this directory was selected - external
            %code will then update the GUI
            app.DirectorySelected(app.SelectedDir);
        end

        % Button pushed function: QueueButton
        function QueueButtonPushed(app, event)
            
            instrRef = app.GetSelectedInstrument();
            commandStr = app.GetCommandString();
            
            eventArgs = Palladium.Events.CommandEventData(instrRef, commandStr);
            notify(app, "SingleCommandQueued", eventArgs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PalladiumSequenceEditorUIFigure = uifigure('Visible', 'off');
            app.PalladiumSequenceEditorUIFigure.Name = 'Palladium Sequence Editor';
            app.PalladiumSequenceEditorUIFigure.Icon = 'C:\Users\coakmj\OneDrive - University of Birmingham\Physics\Matlab\Palladium DAQ\Palladium DAQ\+Palladium\+Components\Graphics\PalladiumDAQIcon.png';
            app.GridLayout = uigridlayout(app.PalladiumSequenceEditorUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BrowseButton = uibutton(app.GridLayout3, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = '📂 Browse';
            app.TextArea = uitextarea(app.GridLayout3);
            app.DirectoryLabel = uilabel(app.GridLayout3);
            app.DirectoryLabel.Text = 'Directory';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = '🔁 Refresh';
            app.Tree = uitree(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.InstrumentDropDownLabel = uilabel(app.GridLayout4);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.GridLayout4);
            app.InstrumentDropDown.Items = {};
            app.InstrumentDropDown.Value = {};
            app.RunCommandsLabel = uilabel(app.GridLayout4);
            app.RunCommandsLabel.Text = 'Run Commands';
            app.CommandEditField = uieditfield(app.GridLayout4, 'text');
            app.CommandEditField.Value = 'PrintIdentifier()';
            app.CommandEditFieldLabel = uilabel(app.GridLayout4);
            app.CommandEditFieldLabel.Text = 'Command';
            app.QueueButton = uibutton(app.GridLayout4, 'push');
            app.QueueButton.ButtonPushedFcn = createCallbackFcn(app, @QueueButtonPushed, true);
            app.QueueButton.Tooltip = {'Add this command to the queue of commands to be executed at the start of subsequent measurement ticks.'};
            app.QueueButton.Text = 'Queue';
            % Show the figure after all components are created
            app.PalladiumSequenceEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SequenceEditor_DefaultGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PalladiumSequenceEditorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PalladiumSequenceEditorUIFigure)
        end
    end
end